classdef final_project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlayGreenSleevesButton_3  matlab.ui.control.Button
        A3Button                  matlab.ui.control.Button
        B3Button                  matlab.ui.control.Button
        C4Button                  matlab.ui.control.Button
        D4Button                  matlab.ui.control.Button
        E4Button                  matlab.ui.control.Button
        F4Button                  matlab.ui.control.Button
        G4Button                  matlab.ui.control.Button
        C5Button                  matlab.ui.control.Button
        A4Button                  matlab.ui.control.Button
        B4Button                  matlab.ui.control.Button
        Cs4Button                 matlab.ui.control.Button
        As3Button                 matlab.ui.control.Button
        Fs4Button                 matlab.ui.control.Button
        Ds4Button                 matlab.ui.control.Button
        Gs4Button                 matlab.ui.control.Button
        As4Button                 matlab.ui.control.Button
        PlayHeisAPirateButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        % set the frequency
        fs = 8000;
        
        % The piano notes array
        piano_notes = ["A3","As3","B3","C4","Cs4","D4","Ds4","E4","F4","Fs4","G4","Gs4","A4","As4","B4","C5"];
        % set A3 to be the base note with 220Hz
        base_frequency = 220; % the frequency of the lowest note
    
        % the array to play the whole song
        play_song = [];
    end

    
    methods (Access = private)
        
        function [tone] = single_note(app,note, N)
            
            % find the place the note in the notes array
            note_location = find(app.piano_notes == note);
            % calculate the frequency of the note
            % using f = fb * 2^(1*(N-1)/12), N - the notes location in the piano array,
            % fb - the base frequency A3
            note_frequency = app.base_frequency * 2.^(1*(note_location - 1)/12);

            % set the time vec using N - note's interval * fs;
            t = (0:1:N-1)./app.fs;
            
            % calculate the tone
            y = sin(2 * pi * note_frequency .* t) .* exp(-0.0015 * 2 * pi * note_frequency .* t);

            y = y + 0.4*sin(1 * 2 * pi * note_frequency .* t) .* exp(-0.0015 * 2 * pi * note_frequency .* t);
            y = y + 0.6*sin(2 * 2 * pi * note_frequency .* t) .* exp(-0.0015 * 2 * pi * note_frequency .* t);

            y = y + y.*y.*y;
            
            tone = y .* (1 + (16 .* t .* exp(-5 .* t)));
             
        end
        
        function play_note_simple(app, note, interval)
            play_vec = [];
            % make the frequency array for the given str
            % create the sound for the digit str(k)
            play_vec(end+1:1:end+floor(interval*app.fs)) = single_note(app,note, floor(interval*app.fs));
        
            soundsc(play_vec, app.fs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayGreenSleevesButton_3
        function PlayGreenSleevesButton_3Pushed(app, event)
            % read notes file:
            notes_array = readmatrix('greensleeves_notes.txt','OutputType','string');
            % read intervals file:
            intervals_array = readmatrix('greensleeves_intervals.xlsx');
            
            
            for k = 1:1:length(notes_array)
                % keep adding every note by calling "single note" function for each note in
                % the notes array.
                app.play_song(end + 1:1:end + intervals_array(k)*app.fs) = single_note(app,notes_array(k), floor(intervals_array(k)*app.fs)); 
            end
            
            soundsc(app.play_song, app.fs);
            app.play_song = [];
            

        end

        % Button pushed function: PlayHeisAPirateButton
        function PlayHeisAPirateButtonPushed(app, event)
            % read notes file:
            notes_array = readmatrix('HeIsAPirate_notes.txt','OutputType','string');
            % read intervals file:
            intervals_array = readmatrix('HeIsAPirate_intervals.xlsx');
            
            for k = 1:1:length(notes_array)
                % keep adding every note by calling "single note" function for each note in
                % the notes array.
                app.play_song(end + 1:1:end + intervals_array(k)*app.fs) = single_note(app,notes_array(k), floor(intervals_array(k)*app.fs)); 
            end
            
            soundsc(app.play_song, app.fs);
            app.play_song = [];

        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            play_note_simple(app,'A3', 0.5);
            app.A3Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: As3Button
        function As3ButtonPushed(app, event)
            play_note_simple(app,'As3', 0.5);
            app.As3Button.BackgroundColor = [0 0 0];
        end

        % Button pushed function: B3Button
        function B3ButtonPushed(app, event)
            play_note_simple(app,'B3', 0.5);
            app.B3Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: C4Button
        function C4ButtonPushed(app, event)
            play_note_simple(app,'C4', 0.5);
            app.C4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: Cs4Button
        function Cs4ButtonPushed(app, event)
            play_note_simple(app,'Cs4', 0.5);
            app.Cs4Button.BackgroundColor = [0 0 0];
        end

        % Button pushed function: D4Button
        function D4ButtonPushed(app, event)
            play_note_simple(app,'D4', 0.5);
            app.D4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: Ds4Button
        function Ds4ButtonPushed(app, event)
            play_note_simple(app,'Ds4', 0.5);
            app.Ds4Button.BackgroundColor = [0 0 0];
        end

        % Button pushed function: E4Button
        function E4ButtonPushed(app, event)
            play_note_simple(app,'E4', 0.5);
            app.E4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: F4Button
        function F4ButtonPushed(app, event)
            play_note_simple(app,'F4', 0.5);
            app.F4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: Fs4Button
        function Fs4ButtonPushed(app, event)
            play_note_simple(app,'Fs4', 0.5);
            app.Fs4Button.BackgroundColor = [0 0 0];
        end

        % Button pushed function: G4Button
        function G4ButtonPushed(app, event)
            play_note_simple(app,'G4', 0.5);
            app.G4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: Gs4Button
        function Gs4ButtonPushed(app, event)
            play_note_simple(app,'Gs4', 0.5);
            app.Gs4Button.BackgroundColor = [0 0 0];
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            play_note_simple(app,'A4', 0.5);
            app.A4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: As4Button
        function As4ButtonPushed(app, event)
            play_note_simple(app,'As4', 0.5);
            app.As4Button.BackgroundColor = [0 0 0];
        end

        % Button pushed function: B4Button
        function B4ButtonPushed(app, event)
            play_note_simple(app,'B4', 0.5);
            app.B4Button.BackgroundColor = [1 1 1];
        end

        % Button pushed function: C5Button
        function C5ButtonPushed(app, event)
            play_note_simple(app,'C5', 0.5);
            app.C5Button.BackgroundColor = [1 1 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayGreenSleevesButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayGreenSleevesButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayGreenSleevesButton_3Pushed, true);
            app.PlayGreenSleevesButton_3.Text = 'Play - GreenSleeves';
            app.A3Button = uibutton(app.UIFigure, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Tag = 'A3';
            app.A3Button.Text = 'A3';
            app.B3Button = uibutton(app.UIFigure, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3Button.Tag = 'B3';
            app.B3Button.Text = 'B3';
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4Button.Tag = 'C4';
            app.C4Button.Text = 'C4';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4Button.Tag = 'D4';
            app.D4Button.Text = 'D4';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4Button.Tag = 'E4';
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4Button.Tag = 'F4';
            app.F4Button.Text = 'F4';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4Button.Tag = 'G4';
            app.G4Button.Text = 'G4';
            app.C5Button = uibutton(app.UIFigure, 'push');
            app.C5Button.ButtonPushedFcn = createCallbackFcn(app, @C5ButtonPushed, true);
            app.C5Button.Tag = 'C5';
            app.C5Button.Text = 'C5';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Tag = 'A4';
            app.A4Button.Text = 'A4';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4Button.Tag = 'B4';
            app.B4Button.Text = 'B4';
            app.Cs4Button = uibutton(app.UIFigure, 'push');
            app.Cs4Button.ButtonPushedFcn = createCallbackFcn(app, @Cs4ButtonPushed, true);
            app.Cs4Button.Tag = 'Cs4';
            app.Cs4Button.Text = 'Cs4';
            app.As3Button = uibutton(app.UIFigure, 'push');
            app.As3Button.ButtonPushedFcn = createCallbackFcn(app, @As3ButtonPushed, true);
            app.As3Button.Tag = 'As3';
            app.As3Button.Text = 'As3';
            app.Fs4Button = uibutton(app.UIFigure, 'push');
            app.Fs4Button.ButtonPushedFcn = createCallbackFcn(app, @Fs4ButtonPushed, true);
            app.Fs4Button.Tag = 'Fs4';
            app.Fs4Button.Text = 'Fs4';
            app.Ds4Button = uibutton(app.UIFigure, 'push');
            app.Ds4Button.ButtonPushedFcn = createCallbackFcn(app, @Ds4ButtonPushed, true);
            app.Ds4Button.Tag = 'Ds4';
            app.Ds4Button.Text = 'Ds4';
            app.Gs4Button = uibutton(app.UIFigure, 'push');
            app.Gs4Button.ButtonPushedFcn = createCallbackFcn(app, @Gs4ButtonPushed, true);
            app.Gs4Button.Tag = 'Gs4';
            app.Gs4Button.Text = 'Gs4';
            app.As4Button = uibutton(app.UIFigure, 'push');
            app.As4Button.ButtonPushedFcn = createCallbackFcn(app, @As4ButtonPushed, true);
            app.As4Button.Tag = 'As4';
            app.As4Button.Text = 'As4';
            app.PlayHeisAPirateButton = uibutton(app.UIFigure, 'push');
            app.PlayHeisAPirateButton.ButtonPushedFcn = createCallbackFcn(app, @PlayHeisAPirateButtonPushed, true);
            app.PlayHeisAPirateButton.Text = 'Play - He is A Pirate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end